classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        MetricsDropDown       matlab.ui.control.DropDown
        MetricsDropDownLabel  matlab.ui.control.Label
        TickerDropDown        matlab.ui.control.DropDown
        TickerDropDownLabel   matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data table  % This will store the CSV data
    end
    
    methods (Access = private)

    function updatePlot(app)
        % 1. Get the currently selected ticker and metric
        selectedTicker = app.TickerDropDown.Value;
        selectedMetric = app.MetricsDropDown.Value;

        % 2. Filter data for the selected ticker
        filteredData = app.data(strcmp(app.data.TickerSymbol, selectedTicker), :);

        % 3. Clear the UIAxes
        cla(app.UIAxes);

        % 4. Plot the chosen metric if it exists
        if ismember(selectedMetric, app.data.Properties.VariableNames)
            plot(app.UIAxes, filteredData.('Period Ending'), ...
                 filteredData.(selectedMetric), 'LineWidth', 1.5);
            title(app.UIAxes, [selectedMetric, ' Over Time for ', selectedTicker]);
            xlabel(app.UIAxes, 'Period Ending');
            ylabel(app.UIAxes, selectedMetric);
            grid(app.UIAxes, 'on');

            % Optional formatting
            app.UIAxes.XTickLabelRotation = 45;
            app.UIAxes.XAxis.TickLabelFormat = 'yyyy';  % Only display the year
        else
            uialert(app.UIFigure, ...
                ['Metric "', selectedMetric, '" not found.'], ...
                'Data Error');
        end
    end

end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            % 1. Load and preprocess CSV data
            app.data = readtable('fundamentals.csv','VariableNamingRule','preserve');
            app.data.Properties.VariableNames{'Ticker Symbol'} = 'TickerSymbol';
            app.data.('Period Ending') = datetime(app.data.('Period Ending'),'InputFormat','yyyy-MM-dd');
    
            % 2. Populate dropdowns
            uniqueTickers = unique(app.data.TickerSymbol);
            app.TickerDropDown.Items = uniqueTickers;
            app.TickerDropDown.Value = uniqueTickers{1};
    
            metricList = {'Net Income','Total Revenue','Total Assets','Earnings Per Share','Current Ratio'};
            app.MetricsDropDown.Items = metricList;
            app.MetricsDropDown.Value = metricList{1};
    
            % 3. (Optional) Call updatePlot(app) to show a default plot
            updatePlot(app);

            disp(app.data.Properties.VariableNames)

            
        end

        % Value changed function: TickerDropDown
        function TickerDropDownValueChanged(app, event)
            % Retrieve the selected ticker from the dropdown
            selectedTicker = app.TickerDropDown.Value;
    
            % (Optional) Display the selected ticker in the Command Window for debugging
            disp(['Ticker selected: ', selectedTicker]);
        
            % Call the updatePlot function to refresh the plot based on the new ticker
            updatePlot(app);
        end

        % Value changed function: MetricsDropDown
        function MetricsDropDownValueChanged(app, event)
            % Retrieve the selected metric from the dropdown
            selectedMetric = app.MetricsDropDown.Value;
        
            % (Optional) Display the selected metric in the Command Window for debugging
            disp(['Metric selected: ', selectedMetric]);
        
            % Call the updatePlot function to refresh the plot based on the new metric
            updatePlot(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TickerDropDownLabel = uilabel(app.UIFigure);
            app.TickerDropDownLabel.Text = 'Ticker Drop Down';
            app.TickerDropDown = uidropdown(app.UIFigure);
            app.TickerDropDown.ValueChangedFcn = createCallbackFcn(app, @TickerDropDownValueChanged, true);
            app.MetricsDropDownLabel = uilabel(app.UIFigure);
            app.MetricsDropDownLabel.Text = 'Metrics';
            app.MetricsDropDown = uidropdown(app.UIFigure);
            app.MetricsDropDown.ValueChangedFcn = createCallbackFcn(app, @MetricsDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end